classdef TIPAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        VanishedPointTab                matlab.ui.container.Tab
        Starttour0Button                matlab.ui.control.Button
        Selectforeground0OptionalButton  matlab.ui.control.Button
        Selectfloorpoint0Button         matlab.ui.control.Button
        VanishedPointTab_2              matlab.ui.container.Tab
        SelectVanishingPointandBackground1Button  matlab.ui.control.Button
        StartTour1Button                matlab.ui.control.Button
        Selectforground1OptionalButton  matlab.ui.control.Button
        VanishedPointsTab               matlab.ui.container.Tab
        Select2PerpendicularLinesButton  matlab.ui.control.Button
        ImageRectificationButton        matlab.ui.control.Button
        Select2ParallelLinesButton      matlab.ui.control.Button
        PredefinedImagesLabel           matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        LoadButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ImageAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Current_Image% Current image path
        
        % o means this parameter is for 0-vanished-point case
        % 1 means this parameter is for 1-vanished-point case
        Fore_ground_1 % Foreground object array
        Fore_ground_0
        hline1 % lines of 5 rectangles
        hline0 
        orx1   % x coordinates outer rectangle
        ory1   % y coordinates outer rectangle
        orx0
        ory0
        irx1   % x coordinates inner rectangle
        iry1   % y coordinates inner rectangle
        irx0
        iry0
        vx1    % x coordinate of vanished point
        vy1    % y coordinate of vanished point
        vx0
        vy0
        img    % imread img 
        Num_Fore_ground_1  % number of fore ground object
        Num_Fore_ground_0
        Fore_rect_1        % list of user-chosen rectangles
        Fore_mask_1        % list of foreground object mask
        Fore_rect_0 % Description
        Fore_mask_0
        img_rectified      % rectified image (when there is 2 
                           % vanished point this is necessary)
        
        % parallel points (2 vanished points)                  
        pp1
        pp2
        pp3
        pp4
        pp5
        pp6
        pp7
        pp8

        % perpendicular points (2 vanished points)
        pep1
        pep2
        pep3
        pep4
        pep5
        pep6
        pep7
        pep8

        img_after_affine % for 2 vanished point case, image after affine
        img_after_metric % for 2 vanished point case, image after metric
        Haffine          % homography matrix after affine transformation
    end
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            % update the image in the UIfigure axis
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end
            app.img = im;
            imagesc(app.ImageAxes, im);
            
        end
        

        function new_rear_rectan_1(app, src, evt, vanishedPoint)
            
            % listener function of image.roi.Rect in 1 vanished point case
            % to changle the 5 rectangles layout when rear rectangle
            % changes

            % change the coordinate of rear rectangle and vanished points 
            irx = round([evt.CurrentPosition(1), evt.CurrentPosition(1) + evt.CurrentPosition(3), ...
            evt.CurrentPosition(1) + evt.CurrentPosition(3), ...
            evt.CurrentPosition(1), evt.CurrentPosition(1)]);
            iry = round([evt.CurrentPosition(2), evt.CurrentPosition(2) , ...
            evt.CurrentPosition(2) + evt.CurrentPosition(4),...
            evt.CurrentPosition(2)+ evt.CurrentPosition(4), evt.CurrentPosition(2)]);
            vx = vanishedPoint.Position(1);
            vy = vanishedPoint.Position(2);

            % assign to app properties
            app.vx1 = vx;
            app.vy1 = vy;

            % select corresponding outer point coordinates
            [height, width, ~] = size(app.img);
            [ox,oy] = find_corner(vx,vy,irx(1),iry(1),0,0);
            orx(1) = ox;  ory(1) = oy;
            [ox,oy] = find_corner(vx,vy,irx(2),iry(2),width,0);
            orx(2) = ox;  ory(2) = oy;
            [ox,oy] = find_corner(vx,vy,irx(3),iry(3),width,height);
            orx(3) = ox;  ory(3) = oy;
            [ox,oy] = find_corner(vx,vy,irx(4),iry(4),0,height);
            orx(4) = ox;  ory(4) = oy;
            app.orx1 = round(orx);
            app.ory1 = round(ory);
            app.irx1 = irx;
            app.iry1 = iry;

            % delete the elements in image with tag "line" 
            axesHandlesToChildObjects = findobj(app.ImageAxes, 'Type', 'line');
            if ~isempty(axesHandlesToChildObjects)
                delete(axesHandlesToChildObjects);
            end 
            delete(app.hline1)
            
            % draw the line according to new irx, iry
            app.hline1(1) = line(app.UIAxes, [vx app.irx1(1)], ...
                [vy app.iry1(1)], 'Color','b', 'tag', 'line');
            app.hline1(2) = line(app.UIAxes, [app.orx1(1) app.irx1(1)],...
                [app.ory1(1) app.iry1(1)], 'Color','b', 'tag', 'line');
            app.hline1(3) = line(app.UIAxes, [vx app.irx1(2)], ...
                [vy app.iry1(2)], 'Color','b','tag', 'line' );
            app.hline1(4) = line(app.UIAxes, [app.orx1(2) app.irx1(2)],...
                [app.ory1(2) app.iry1(2)], 'Color','b', 'tag', 'line');
            app.hline1(5) = line(app.UIAxes, [vx app.irx1(3)], ...
                [vy app.iry1(3)], 'Color','b', 'tag', 'line');
            app.hline1(6) = line(app.UIAxes, [app.orx1(3) app.irx1(3)],...
                [app.ory1(3) app.iry1(3)], 'Color','b','tag', 'line' );
            app.hline1(7) = line(app.UIAxes, [vx app.irx1(4)], ...
                [vy app.iry1(4)], 'Color','b', 'tag', 'line');
            app.hline1(8) = line(app.UIAxes, [app.orx1(4) app.irx1(4)],...
                [app.ory1(4) app.iry1(4)], 'Color','b', 'tag', 'line');
        end


        function new_vanished_point_1(app, src, evt, rear_rectan)
            % listener function of image.roi.Point in 1 vanished point case
            % to changle the 5 rectangles layout when vanished point
            % changes
            % get the current vanished point's position
            vx = evt.CurrentPosition(1);
            vy = evt.CurrentPosition(2);
            
            % assign to app properties
            app.vx1 = vx;
            app.vy1 = vy;

            % keep inner rect unchanged
            % select corresponding outer point coordinates
            [height, width, ~] = size(app.img);
            irx = round([rear_rectan.Position(1), rear_rectan.Position(1) + rear_rectan.Position(3), ...
            rear_rectan.Position(1) + rear_rectan.Position(3), ...
            rear_rectan.Position(1), rear_rectan.Position(1)]);
            iry = round([rear_rectan.Position(2), rear_rectan.Position(2) , ...
            rear_rectan.Position(2) + rear_rectan.Position(4),...
            rear_rectan.Position(2)+ rear_rectan.Position(4), rear_rectan.Position(2)]);
            [ox,oy] = find_corner(vx,vy,irx(1),iry(1),0,0);
            orx(1) = ox;  ory(1) = oy;
            [ox,oy] = find_corner(vx,vy,irx(2),iry(2),width,0);
            orx(2) = ox;  ory(2) = oy;
            [ox,oy] = find_corner(vx,vy,irx(3),iry(3),width,height);
            orx(3) = ox;  ory(3) = oy;
            [ox,oy] = find_corner(vx,vy,irx(4),iry(4),0,height);
            orx(4) = ox;  ory(4) = oy;
            app.orx1 = round(orx);
            app.ory1 = round(ory);
            app.irx1 = irx;
            app.iry1 = iry;
            
            % delete the elements in image with tag "line" 
            axesHandlesToChildObjects = findobj(app.UIAxes, 'Type', 'line');
            if ~isempty(axesHandlesToChildObjects)
                delete(axesHandlesToChildObjects);
            end 
            delete(app.hline1)
            

            % draw the line according to new irx, iry
            app.hline1(1) = line(app.UIAxes, [vx app.irx1(1)], ...
                [vy app.iry1(1)], 'Color','b', 'tag', 'line');
            app.hline1(2) = line(app.UIAxes, [app.orx1(1) app.irx1(1)],...
                [app.ory1(1) app.iry1(1)], 'Color','b', 'tag', 'line');
            app.hline1(3) = line(app.UIAxes, [vx app.irx1(2)], ...
                [vy app.iry1(2)], 'Color','b','tag', 'line' );
            app.hline1(4) = line(app.UIAxes, [app.orx1(2) app.irx1(2)],...
                [app.ory1(2) app.iry1(2)], 'Color','b', 'tag', 'line');
            app.hline1(5) = line(app.UIAxes, [vx app.irx1(3)], ...
                [vy app.iry1(3)], 'Color','b', 'tag', 'line');
            app.hline1(6) = line(app.UIAxes, [app.orx1(3) app.irx1(3)],...
                [app.ory1(3) app.iry1(3)], 'Color','b','tag', 'line' );
            app.hline1(7) = line(app.UIAxes, [vx app.irx1(4)], ...
                [vy app.iry1(4)], 'Color','b', 'tag', 'line');
            app.hline1(8) = line(app.UIAxes, [app.orx1(4) app.irx1(4)],...
                [app.ory1(4) app.iry1(4)], 'Color','b', 'tag', 'line');
        end


        function new_rear_rectan_0(app, src, evt, vanishedPoint)
            
            % listener function of image.roi.Point in 0 vanished point case
            % to changle the 2 rectangles layout when vanished point
            % changes
            % get the current vanished point's position
            irx = round([evt.CurrentPosition(1), evt.CurrentPosition(1) + evt.CurrentPosition(3), ...
            evt.CurrentPosition(1) + evt.CurrentPosition(3), ...
            evt.CurrentPosition(1), evt.CurrentPosition(1)]);
            iry = round([evt.CurrentPosition(2), evt.CurrentPosition(2) , ...
            evt.CurrentPosition(2) + evt.CurrentPosition(4),...
            evt.CurrentPosition(2)+ evt.CurrentPosition(4), evt.CurrentPosition(2)]);
            vx = vanishedPoint.Position(1);
            vy = vanishedPoint.Position(2);
            app.vx0 = vx;
            app.vy0 = vy;
            [height, width, ~] = size(app.img);
            [ox,oy] = find_corner(vx,vy,irx(3),iry(3),width,height);
            orx(1) = ox;  ory(1) = oy;
            [ox,oy] = find_corner(vx,vy,irx(4),iry(4),0,height);
            orx(2) = ox;  ory(2) = oy;
            app.orx0 = round(orx);
            app.ory0 = round(ory);
            app.irx0 = irx;
            app.iry0 = iry;

            % delete the elements in image with tag "line" 
            axesHandlesToChildObjects = findobj(app.UIAxes, 'Type', 'line');
            if ~isempty(axesHandlesToChildObjects)
                delete(axesHandlesToChildObjects);
            end 
            delete(app.hline0)
            
            % draw the line according to new irx, iry
            app.hline0(1) = line(app.UIAxes, [vx app.irx0(3)], ...
                [vy app.iry0(3)], 'Color','b', 'tag', 'line');
            app.hline0(2) = line(app.UIAxes, [app.orx0(1) app.irx0(3)],...
                [app.ory0(1) app.iry0(3)], 'Color','b','tag', 'line' );
            app.hline0(3) = line(app.UIAxes, [vx app.irx0(4)], ...
                [vy app.iry0(4)], 'Color','b', 'tag', 'line');
            app.hline0(4) = line(app.UIAxes, [app.orx0(2) app.irx0(4)],...
                [app.ory0(2) app.iry0(4)], 'Color','b', 'tag', 'line');
        end

        function new_vanished_point_0(app, src, evt, rear_rectan)
            % listener function of image.roi.Point in 0 vanished point case
            % to changle the 2 rectangles layout when vanished point
            % changes
            % get the current vanished point's position
            vx = evt.CurrentPosition(1);
            vy = evt.CurrentPosition(2);
            
            app.vx1 = vx;
            app.vy1 = vy;
            [height, width, ~] = size(app.img);
            
            irx = round([rear_rectan.Position(1), rear_rectan.Position(1) + rear_rectan.Position(3), ...
            rear_rectan.Position(1) + rear_rectan.Position(3), ...
            rear_rectan.Position(1), rear_rectan.Position(1)]);
            iry = round([rear_rectan.Position(2), rear_rectan.Position(2) , ...
            rear_rectan.Position(2) + rear_rectan.Position(4),...
            rear_rectan.Position(2)+ rear_rectan.Position(4), rear_rectan.Position(2)]);
            
            [ox,oy] = find_corner(vx,vy,irx(3),iry(3),width,height);
            orx(1) = ox;  ory(1) = oy;
            [ox,oy] = find_corner(vx,vy,irx(4),iry(4),0,height);
            orx(2) = ox;  ory(2) = oy;
            app.orx0 = round(orx);
            app.ory0 = round(ory);
            app.irx0 = irx;
            app.iry0 = iry;
            
            % delete the elements in image with tag "line" 
            axesHandlesToChildObjects = findobj(app.UIAxes, 'Type', 'line');
            if ~isempty(axesHandlesToChildObjects)
                delete(axesHandlesToChildObjects);
            end 
            delete(app.hline0)
           
            % draw the line according to new irx, iry
            app.hline0(1) = line(app.UIAxes, [vx app.irx0(3)], ...
                [vy app.iry0(3)], 'Color','b', 'tag', 'line');
            app.hline0(2) = line(app.UIAxes, [app.orx0(1) app.irx0(3)],...
                [app.ory0(1) app.iry0(3)], 'Color','b','tag', 'line' );
            app.hline0(3) = line(app.UIAxes, [vx app.irx0(4)], ...
                [vy app.iry0(4)], 'Color','b', 'tag', 'line');
            app.hline0(4) = line(app.UIAxes, [app.orx0(2) app.irx0(4)],...
                [app.ory0(2) app.iry0(4)], 'Color','b', 'tag', 'line');
        end

        
        % call back function of roi 1st parallel line 
        function new_parallel_1(app, src, evt)
            app.pp1 = [evt.CurrentPosition(1, 1), evt.CurrentPosition(1,2)];
            app.pp2 = [evt.CurrentPosition(2, 1), evt.CurrentPosition(2,2)];
            
        end

        % callback function of roi 2nd parallel line
        function new_parallel_2(app, src, evt)
            app.pp3 = [evt.CurrentPosition(1, 1), evt.CurrentPosition(1,2)];
            app.pp4 = [evt.CurrentPosition(2, 1), evt.CurrentPosition(2,2)];
        end

        % callback function of roi 3rd parallel line
        function new_parallel_3(app, src, evt)
            app.pp5 = [evt.CurrentPosition(1, 1), evt.CurrentPosition(1,2)];
            app.pp6 = [evt.CurrentPosition(2, 1), evt.CurrentPosition(2,2)];
        end

        % callback function of 4th parallel line
        function new_parallel_4(app, src, evt)
            app.pp7 = [evt.CurrentPosition(1, 1), evt.CurrentPosition(1,2)];
            app.pp8 = [evt.CurrentPosition(2, 1), evt.CurrentPosition(2,2)];
        end

        % callback function of 1st perpendicular line
        function new_perpendicular_1(app, src, evt)
            app.pep1 = [evt.CurrentPosition(1, 1), evt.CurrentPosition(1, 2)];
            app.pep2 = [evt.CurrentPosition(2, 1), evt.CurrentPosition(2, 2)];
        end

        % callback function of 2nd perpendicular line
        function new_perpendicular_2(app, src, evt)
            app.pep3 = [evt.CurrentPosition(1, 1), evt.CurrentPosition(1, 2)];
            app.pep4 = [evt.CurrentPosition(2, 1), evt.CurrentPosition(2, 2)];
        end

        % callback function of 3rd perpendicular line
        function new_perpendicular_3(app, src, evt)
            app.pep5 = [evt.CurrentPosition(1, 1), evt.CurrentPosition(1, 2)];
            app.pep6 = [evt.CurrentPosition(2, 1), evt.CurrentPosition(2, 2)];
        end

        % callback function of 4th perpendicular line
        function new_perpendicular_4(app, src, evt)
            app.pep7 = [evt.CurrentPosition(1, 1), evt.CurrentPosition(1, 2)];
            app.pep8 = [evt.CurrentPosition(2, 1), evt.CurrentPosition(2, 2)];
        end

    end 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set the app position

            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            app.ImageAxes.Units =  "pixels";
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];

            axis(app.ImageAxes, 'image');
            app.Current_Image = app.DropDown.Value;
            % Update the image 
            updateimage(app, app.Current_Image);
            app.Num_Fore_ground_1 = 0;
            app.Num_Fore_ground_0 = 0;
            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            app.UIAxes.Units =  "pixels";
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];

            % keep the axes static
            cla(app.UIAxes,'reset')
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
     
            % update the preview image in the axes
            app.Current_Image = app.DropDown.Value;
            updateimage(app, app.Current_Image);
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif;*.jpeg','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               app.Current_Image = fname;
               updateimage(app, fname);
            end
        end

        % Button down function: VanishedPointTab_2
        function VanishedPointTab_2ButtonDown(app, event)
            %% this is callback function for tab-1 vanished point
            % clean the axis first
            axesHandlesToChildLines = findobj(app.UIAxes, 'Type', 'line');
            if ~isempty(axesHandlesToChildLines)
                delete(axesHandlesToChildLines);
            end 
            
            axesHandlesToChildPoints = findobj(app.UIAxes, 'Type', 'images.roi.Point');
            if ~isempty(axesHandlesToChildPoints)
                delete(axesHandlesToChildPoints);
            end 

            axesHandlesToChildRects = findobj(app.UIAxes, 'Type', 'images.roi.Rectangle');
            if ~isempty(axesHandlesToChildRects)
                delete(axesHandlesToChildRects);
            end 

            axesHandlesToChildLines = findobj(app.UIAxes, 'Type', 'images.roi.Line');
            if ~isempty(axesHandlesToChildLines)
                delete(axesHandlesToChildLines);
            end 
            
            % when the tab button is pushed, reset the ploted image
            app.Num_Fore_ground_1 = 0;
            app.Num_Fore_ground_0 = 0;
            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            app.UIAxes.Units =  "pixels";
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            cla(app.UIAxes,'reset');
            goal_img = app.img;
            axis(app.UIAxes, 'image');
            imagesc(app.UIAxes, goal_img);
            
        end

        % Button pushed function: StartTour1Button
        function StartTour1ButtonPushed(app, event)
         
            % click the start tour button and begin to tour
            % J is foreground object
            % rect is user-selected rectangle
            % img filled is the repaired img after foreground interpolation
            % read the forground list
            if ~isequal(app.Num_Fore_ground_1, 0)
                 for i=1:app.Num_Fore_ground_1
                    [img_filled,J{i},rect{i},~] = fill_the_hole(app.img, app.Fore_ground_1{i}, app.Fore_rect_1{i}, app.Fore_mask_1{i});
                    app.img = img_filled;
                 end

            else
                J = 0;
                rect = [0,0,0,0];
            end
            
            % read the stored walls from app properties
            rear_wall_x = app.irx1;
            rear_wall_y = app.iry1;
            vanished_point_x = app.vx1;
            vanished_point_y = app.vy1;
            rand_intersection_x = app.orx1;
            rand_intersection_y = app.ory1;
            
            
            % get 5 rects from current vanished point and rear wall
            [bim, vx, vy, ceilx, ceily, floorx, floory, leftx, lefty, rightx, ...
                righty, backx, backy, lmargin, tmargin] = get5rects(app.img, vanished_point_x, ...
                vanished_point_y, rear_wall_x, rear_wall_y, ...
                rand_intersection_x, rand_intersection_y);
            
            % start the tour with foreground object if it exits
            walk_through_3d_app(bim, vx, vy, ceilx, ceily, floorx, floory, leftx, lefty, rightx, ...
                righty, backx, backy, lmargin, tmargin, J, rect, app.Num_Fore_ground_1);

        end

        % Button pushed function: Selectforground1OptionalButton
        function Selectforground1OptionalButtonPushed(app, event)
            %% Clicke the button "Select Foreground"
            %% only choose one object every click
            app.Num_Fore_ground_1 = app.Num_Fore_ground_1 + 1;
            num_fore_ground = app.Num_Fore_ground_1;

            path = convertStringsToChars(app.Current_Image);
            img_fore = imread(path);

            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            axis(app.UIAxes, 'image');
            cla(app.UIAxes,'reset');
            imagesc(app.UIAxes, img_fore);
            hold(app.UIAxes);
            app.img = img_fore;
            
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure);

            k = 0;
            p = [];
            while 1
                [x,y,flag] = ginput(1); % returns the mouse button used to select each point
                if flag == 1 % confirm whether it is the left mouse button input
                    k = k + 1;
                    plot(app.UIAxes,x,y,'b.','MarkerSize',20)
                    p(k,1:2) = round([y,x]);
                    if k>1
                       line(app.UIAxes,[p(k-1,2),p(k,2)],[p(k-1,1),p(k,1)],'LineWidth',2) 
                    end
                elseif flag == 32
                    k = 0;
                    p = [];
                    break                
                else
                    line(app.UIAxes,[p(1,2),p(k,2)],[p(1,1),p(k,1)],'LineWidth',2)   
                    break
                end    
            end

            
            app.UIFigure.HandleVisibility = fhv; 

            if k ~= 0
                [fore_ground,rect,mask] = foregroundsele_app(img_fore, k, p);
            else
                app.Num_Fore_ground_1 = app.Num_Fore_ground_1 - 1;
                imagesc(app.UIAxes, img_fore);
                return
            end

            app.Fore_ground_1{num_fore_ground} = fore_ground;
            app.Fore_rect_1{num_fore_ground} = rect;
            app.Fore_mask_1{num_fore_ground} = mask;

        end

        % Button down function: VanishedPointTab
        function VanishedPointTabButtonDown(app, event)
            %% this is callback function for tab-0 vanished point
            % clean the axis first
            axesHandlesToChildLines = findobj(app.UIAxes, 'Type', 'line');
            if ~isempty(axesHandlesToChildLines)
                delete(axesHandlesToChildLines);
            end 
            
            axesHandlesToChildPoints = findobj(app.UIAxes, 'Type', 'images.roi.Point');
            if ~isempty(axesHandlesToChildPoints)
                delete(axesHandlesToChildPoints);
            end 

            axesHandlesToChildRects = findobj(app.UIAxes, 'Type', 'images.roi.Rectangle');
            if ~isempty(axesHandlesToChildRects)
                delete(axesHandlesToChildRects);
            end 

            axesHandlesToChildLines = findobj(app.UIAxes, 'Type', 'images.roi.Line');
            if ~isempty(axesHandlesToChildLines)
                delete(axesHandlesToChildLines);
            end 

            % when the tab button is pushed, reset the ploted image
            app.Num_Fore_ground_0 = 0;
            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            app.UIAxes.Units =  "pixels";
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            cla(app.UIAxes,'reset');
            goal_img = app.img;
            axis(app.UIAxes, 'image');
            imagesc(app.UIAxes, goal_img);
        end

        % Button pushed function: SelectVanishingPointandBackground1Button
        function SelectVanishingPointandBackground1ButtonPushed(app, event)
            %% this is callback function of button select vanished 
            %% point and background when there is one vanished point
            
            % clean the pre roi and lines
            axesHandlesToChildLines = findobj(app.UIAxes, 'Type', 'line');
            if ~isempty(axesHandlesToChildLines)
                delete(axesHandlesToChildLines);
            end 
            
            axesHandlesToChildPoints = findobj(app.UIAxes, 'Type', 'images.roi.Point');
            if ~isempty(axesHandlesToChildPoints)
                delete(axesHandlesToChildPoints);
            end 

            axesHandlesToChildRects = findobj(app.UIAxes, 'Type', 'images.roi.Rectangle');
            if ~isempty(axesHandlesToChildRects)
                delete(axesHandlesToChildRects);
            end 
            
            axesHandlesToChildLines = findobj(app.UIAxes, 'Type', 'images.roi.Line');
            if ~isempty(axesHandlesToChildLines)
                delete(axesHandlesToChildLines);
            end 

            goal_img = app.img;
            imagesc(app.UIAxes, goal_img);

            [height, width, ~] = size(goal_img);
            
            % initial plotting of rear wall and vanished point
            x_ul = round(height / 10); y_ul = round(width / 10);
            rear_rectan = images.roi.Rectangle(app.UIAxes, 'Position', ...
                [x_ul, y_ul, width - 2*x_ul, height - 2*y_ul], ...
                'Color','b', 'FaceAlpha',0, 'tag', 'rect');
            % handles.ROIrect = rear_rectan;
            
            vx = round(width / 2);
            vy = round(height / 2);
            vanishedPoint = images.roi.Point(app.UIAxes, 'Position', [vx, vy]);
            vanishedPoint.Label = 'Vanished Point';
            
            % irx, iry: x,y pixel position of the four point of rectange
            irx = round([rear_rectan.Position(1), rear_rectan.Position(1) + rear_rectan.Position(3), ...
            rear_rectan.Position(1) + rear_rectan.Position(3), ...
            rear_rectan.Position(1), rear_rectan.Position(1)]);
            iry = round([rear_rectan.Position(2), rear_rectan.Position(2) , ...
            rear_rectan.Position(2) + rear_rectan.Position(4),...
            rear_rectan.Position(2)+ rear_rectan.Position(4), rear_rectan.Position(2)]);
            
            % search the intertaction with image rand
            [ox,oy] = find_corner(vx,vy,irx(1),iry(1),0,0);
            orx(1) = ox;  ory(1) = oy;
            [ox,oy] = find_corner(vx,vy,irx(2),iry(2),width,0);
            orx(2) = ox;  ory(2) = oy;
            [ox,oy] = find_corner(vx,vy,irx(3),iry(3),width,height);
            orx(3) = ox;  ory(3) = oy;
            [ox,oy] = find_corner(vx,vy,irx(4),iry(4),0,height);
            orx(4) = ox;  ory(4) = oy;
            orx = round(orx);
            ory = round(ory); 
            % draw vanished point and lines, use a array the store the lines
            
            hline(1) = line(app.UIAxes, [vx irx(1)], [vy iry(1)], ...
                'Color','b', 'tag', 'line');
            hline(2) = line(app.UIAxes, [orx(1) irx(1)], [ory(1) iry(1)], ...
                'Color','b', 'tag', 'line');
            hline(3) = line(app.UIAxes, [vx irx(2)], [vy iry(2)], ...
                'Color','b', 'tag', 'line');
            hline(4) = line(app.UIAxes, [orx(2) irx(2)], [ory(2) iry(2)], ...
                'Color','b', 'tag', 'line');
            hline(5) = line(app.UIAxes, [vx irx(3)], [vy iry(3)], ...
                'Color','b', 'tag', 'line');
            hline(6) = line(app.UIAxes, [orx(3) irx(3)], [ory(3) iry(3)], ...
                'Color','b', 'tag', 'line');
            hline(7) = line(app.UIAxes, [vx irx(4)], [vy iry(4)], ...
                'Color','b', 'tag', 'line');
            hline(8) = line(app.UIAxes, [orx(4) irx(4)], [ory(4) iry(4)], ...
                'Color','b', 'tag', 'line');
            
            
            app.hline1 = hline;
            app.orx1 = orx;
            app.ory1 = ory;
            app.irx1 = irx;
            app.iry1 = iry;
            app.vx1 = vx;
            app.vy1 = vy;

            addlistener(rear_rectan, 'MovingROI', ...
                        @(src, evt) new_rear_rectan_1(app, src, evt, ...
                        vanishedPoint));
            addlistener(vanishedPoint, 'MovingROI', ...
                        @(src, evt) new_vanished_point_1(app, src, evt, ...
                        rear_rectan));
            
        end

        % Button pushed function: Selectfloorpoint0Button
        function Selectfloorpoint0ButtonPushed(app, event)
            %% this is callback function of button select vanished 
            %% point and background and floor when there is 0 vanished point
            %% because only 2 rects will be represented, so just back 
            %% and floor is considered
            % clean the pre roi and lines
            
            axesHandlesToChildLines = findobj(app.UIAxes, 'Type', 'line');
            if ~isempty(axesHandlesToChildLines)
                delete(axesHandlesToChildLines);
            end 
            
            axesHandlesToChildPoints = findobj(app.UIAxes, 'Type', 'images.roi.Point');
            if ~isempty(axesHandlesToChildPoints)
                delete(axesHandlesToChildPoints);
            end 

            axesHandlesToChildRects = findobj(app.UIAxes, 'Type', 'images.roi.Rectangle');
            if ~isempty(axesHandlesToChildRects)
                delete(axesHandlesToChildRects);
            end 

            axesHandlesToChildLines = findobj(app.UIAxes, 'Type', 'images.roi.Line');
            if ~isempty(axesHandlesToChildLines)
                delete(axesHandlesToChildLines);
            end 
            
            goal_img = app.img;
           
            [height, width, ~] = size(goal_img);
            
            % initial plotting of rear wall and vanished point
            x_ul = round(height / 10); y_ul = round(width / 10);
            rear_rectan = images.roi.Rectangle(app.UIAxes, 'Position', ...
                [x_ul, y_ul, width - 2*x_ul, height - 2*y_ul], ...
                'Color','b', 'FaceAlpha',0, 'tag', 'rect');

            
            vx = round(width / 2);
            vy = round(height / 2);
            vanishedPoint = images.roi.Point(app.UIAxes, 'Position', [vx, vy]);
            vanishedPoint.Label = 'Vanished Point';
            
            % irx, iry: x,y pixel position of the four point of rectange
            irx = round([rear_rectan.Position(1), rear_rectan.Position(1) + rear_rectan.Position(3), ...
            rear_rectan.Position(1) + rear_rectan.Position(3), ...
            rear_rectan.Position(1), rear_rectan.Position(1)]);
            iry = round([rear_rectan.Position(2), rear_rectan.Position(2) , ...
            rear_rectan.Position(2) + rear_rectan.Position(4),...
            rear_rectan.Position(2)+ rear_rectan.Position(4), rear_rectan.Position(2)]);
            
            % search the intertaction with image rand
            
            [ox,oy] = find_corner(vx,vy,irx(3),iry(3),width,height);
            orx(1) = ox;  ory(1) = oy;
            [ox,oy] = find_corner(vx,vy,irx(4),iry(4),0,height);
            orx(2) = ox;  ory(2) = oy;
            orx = round(orx);
            ory = round(ory); 


            % draw vanished point and lines, use a array the store the lines
            
            hline(1) = line(app.UIAxes, [vx irx(3)], [vy iry(3)], ...
                'Color','b', 'tag', 'line');
            hline(2) = line(app.UIAxes, [orx(1) irx(3)], [ory(1) iry(3)], ...
                'Color','b', 'tag', 'line');
            hline(3) = line(app.UIAxes, [vx irx(4)], [vy iry(4)], ...
                'Color','b', 'tag', 'line');
            hline(4) = line(app.UIAxes, [orx(2) irx(4)], [ory(2) iry(4)], ...
                'Color','b', 'tag', 'line');
            

            app.hline0 = hline;
            app.orx0 = orx;
            app.ory0 = ory;
            app.irx0 = irx;
            app.iry0 = iry;
            app.vx0 = vx;
            app.vy0 = vy;
            
            
            addlistener(rear_rectan, 'MovingROI', ...
                        @(src, evt) new_rear_rectan_0(app, src, evt, ...
                        vanishedPoint));

            addlistener(vanishedPoint, 'MovingROI', ...
                        @(src, evt) new_vanished_point_0(app, src, evt, ...
                        rear_rectan));
            
        end

        % Button pushed function: Selectforeground0OptionalButton
        function Selectforeground0OptionalButtonPushed(app, event)
            %% Clicke the button "Select Foreground"
            %% only choose one object every click
            app.Num_Fore_ground_0 = app.Num_Fore_ground_0 + 1;
            num_fore_ground = app.Num_Fore_ground_0;

            path = convertStringsToChars(app.Current_Image);
            img_fore = imread(path);

            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            axis(app.UIAxes, 'image');
            cla(app.UIAxes,'reset');
            imagesc(app.UIAxes, img_fore);
            hold(app.UIAxes);
            app.img = img_fore;
            
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure);

            k = 0;
            p = [];
            while 1
                [x,y,flag] = ginput(1); % returns the mouse button used to select each point
                if flag == 1 % confirm whether it is the left mouse button input
                    k = k + 1;
                    plot(app.UIAxes,x,y,'b.','MarkerSize',20)
                    p(k,1:2) = round([y,x]);
                    if k>1
                       line(app.UIAxes,[p(k-1,2),p(k,2)],[p(k-1,1),p(k,1)],'LineWidth',2) 
                    end
                elseif flag == 32
                    k = 0;
                    p = [];
                    break                
                else
                    line(app.UIAxes,[p(1,2),p(k,2)],[p(1,1),p(k,1)],'LineWidth',2)   
                    break
                end    
            end

            app.UIFigure.HandleVisibility = fhv; 

            if k ~= 0
                [fore_ground,rect,mask] = foregroundsele_app(img_fore, k, p);
            else
                app.Num_Fore_ground_0 = app.Num_Fore_ground_0 - 1;
                imagesc(app.UIAxes, img_fore);
                return
            end

            app.Fore_ground_0{num_fore_ground} = fore_ground;
            app.Fore_rect_0{num_fore_ground} = rect;
            app.Fore_mask_0{num_fore_ground} = mask;
        end

        % Button pushed function: Starttour0Button
        function Starttour0ButtonPushed(app, event)
            % start tour with 0 vanished point
            % J is foreground object
            % rect is user-selected rectangle
            % img filled is the repaired img after foreground interpolation
            if ~isequal(app.Num_Fore_ground_0, 0)
                 for i=1:app.Num_Fore_ground_0
                    [img_filled,J{i},rect{i},~] = fill_the_hole(app.img, app.Fore_ground_0{i}, app.Fore_rect_0{i}, app.Fore_mask_0{i});
                    app.img = img_filled;
                 end

            else
                J = 0;
                rect = [0,0,0,0];
            end

            rear_wall_x = app.irx0;
            rear_wall_y = app.iry0;
            vanished_point_x = app.vx0;
            vanished_point_y = app.vy0;
            rand_intersection_x = app.orx0;
            rand_intersection_y = app.ory0;

            [bim, vx, vy, floorx, floory, ...
                 backx, backy] = get2rects(app.img, vanished_point_x, ...
                vanished_point_y, rear_wall_x, rear_wall_y, ...
                rand_intersection_x, rand_intersection_y);

            walk_through_3d_0(bim, vx, vy, floorx, floory, ...
                backx, backy, J, rect, app.Num_Fore_ground_0);
        end

        % Button down function: VanishedPointsTab
        function VanishedPointsTabButtonDown(app, event)
            %% this callback function for tab- 2vanished point
            axesHandlesToChildLines = findobj(app.UIAxes, 'Type', 'line');
            if ~isempty(axesHandlesToChildLines)
                delete(axesHandlesToChildLines);
            end 
            
            axesHandlesToChildPoints = findobj(app.UIAxes, 'Type', 'images.roi.Point');
            if ~isempty(axesHandlesToChildPoints)
                delete(axesHandlesToChildPoints);
            end 

            axesHandlesToChildRects = findobj(app.UIAxes, 'Type', 'images.roi.Rectangle');
            if ~isempty(axesHandlesToChildRects)
                delete(axesHandlesToChildRects);
            end 
            
            axesHandlesToChildLines = findobj(app.UIAxes, 'Type', 'images.roi.Line');
            if ~isempty(axesHandlesToChildLines)
                delete(axesHandlesToChildLines);
            end 
            app.Num_Fore_ground_0 = 0;
            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            app.UIAxes.Units =  "pixels";
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            cla(app.UIAxes,'reset')
            goal_img = app.img;
            axis(app.UIAxes, 'image');
            imagesc(app.UIAxes, goal_img);
        end

        % Button pushed function: Select2ParallelLinesButton
        function Select2ParallelLinesButtonPushed(app, event)
            %% this is callback function for button select two parrallel lines
            %% for further iamge rectification
            axesHandlesToChildLines = findobj(app.UIAxes, 'Type', 'line');
            if ~isempty(axesHandlesToChildLines)
                delete(axesHandlesToChildLines);
            end 
            
            axesHandlesToChildPoints = findobj(app.UIAxes, 'Type', 'images.roi.Point');
            if ~isempty(axesHandlesToChildPoints)
                delete(axesHandlesToChildPoints);
            end 

            axesHandlesToChildRects = findobj(app.UIAxes, 'Type', 'images.roi.Rectangle');
            if ~isempty(axesHandlesToChildRects)
                delete(axesHandlesToChildRects);
            end 
            
            axesHandlesToChildLines = findobj(app.UIAxes, 'Type', 'images.roi.Line');
            if ~isempty(axesHandlesToChildLines)
                delete(axesHandlesToChildLines);
            end 
            
            % refresh the UI Axes
            goal_img = app.img;
 
            [height, width, ~] = size(goal_img);

            p_p1 = [100, 100]; p_p2 = [200, 200]; 
            p_p3 = [width - 100, 100]; p_p4 = [width - 200, 200]; 
            p_p5 = [100, height - 100]; p_p6 = [200, height - 200];
            p_p7 = [width - 100, height - 100]; p_p8 = [width - 200, height - 200];

            parallel_line_1_1 = images.roi.Line(app.UIAxes, ...
                'Position', [p_p1; p_p2], 'Color', 'g');
            parallel_line_1_2 = images.roi.Line(app.UIAxes, ...
                'Position', [p_p3; p_p4], 'Color', 'g');

            parallel_line_2_1 = images.roi.Line(app.UIAxes, ...
                'Position', [p_p5; p_p6], 'Color', 'r');
            parallel_line_2_2 = images.roi.Line(app.UIAxes, ...
                'Position', [p_p7; p_p8], 'Color', 'r');
            
            % store in the app properties
            app.pp1 = p_p1;
            app.pp2 = p_p2;
            app.pp3 = p_p3;
            app.pp4 = p_p4;
            app.pp5 = p_p5;
            app.pp6 = p_p6;
            app.pp7 = p_p7;
            app.pp8 = p_p8;

            addlistener(parallel_line_1_1, 'MovingROI', ...
                        @(src, evt) new_parallel_1(app, src, evt));

            addlistener(parallel_line_1_2, 'MovingROI', ...
                        @(src, evt) new_parallel_2(app, src, evt));

            addlistener(parallel_line_2_1, 'MovingROI', ...
                        @(src, evt) new_parallel_3(app, src, evt));

            addlistener(parallel_line_2_2, 'MovingROI', ...
                        @(src, evt) new_parallel_4(app, src, evt));


        end

        % Button pushed function: ImageRectificationButton
        function ImageRectificationButtonPushed(app, event)
            
            Ha = app.Haffine;
            Hm = metricRectification(app.pep1, app.pep2, app.pep3, app.pep4, ...
                                     app.pep5, app.pep6, app.pep7, app.pep8, Ha);
            
            
            img_before = app.img_after_affine;
            
            tform = maketform('projective', Hm');
            [boxx, boxy]=tformfwd(tform, [1 1 size(img_before,2) ...
                size(img_before,2)], [1 size(img_before,1) 1 ...
                size(img_before,1)]);
            minx=min(boxx); maxx=max(boxx);
            miny=min(boxy); maxy=max(boxy);
            img_metric =imtransform(img_before,tform,'XData', ...
                [minx maxx], 'YData',[miny maxy],'Size', ...
                [size(img_before,1), ...
                round(size(img_before,1)*(maxx-minx)/(maxy-miny))]);
            app.img_after_metric = img_metric;
            axis(app.UIAxes, 'image');
            cla(app.UIAxes,'reset')
            imagesc(app.UIAxes, app.img_after_metric);
           
            
        end

        % Button pushed function: Select2PerpendicularLinesButton
        function Select2PerpendicularLinesButtonPushed(app, event)
            %% this is callback function for button select two perpendicular lines
            %% for further iamge rectification
            axesHandlesToChildLines = findobj(app.UIAxes, 'Type', 'line');
            if ~isempty(axesHandlesToChildLines)
                delete(axesHandlesToChildLines);
            end 
            
            axesHandlesToChildPoints = findobj(app.UIAxes, 'Type', 'images.roi.Point');
            if ~isempty(axesHandlesToChildPoints)
                delete(axesHandlesToChildPoints);
            end 

            axesHandlesToChildRects = findobj(app.UIAxes, 'Type', 'images.roi.Rectangle');
            if ~isempty(axesHandlesToChildRects)
                delete(axesHandlesToChildRects);
            end 
            
            axesHandlesToChildLines = findobj(app.UIAxes, 'Type', 'images.roi.Line');
            if ~isempty(axesHandlesToChildLines)
                delete(axesHandlesToChildLines);
            end 
            
            % first affine rectification
            img_before = app.img;
            [img_affine, Ha] = affineRectification(img_before, app.pp1, app.pp2, app.pp3, app.pp4,...
                                    app.pp5, app.pp6, app.pp7, app.pp8);

            % read the image after affien rectification 
            app.img_after_affine = img_affine;
            app.Haffine = Ha;
            axis(app.UIAxes, 'auto');
          
            imagesc(app.UIAxes, app.img_after_affine);
            
            % initialize perpendicular lines
            pe_p1 = [30, 100]; pe_p2 = [70, 140]; 
            pe_p3 = [50, 120]; pe_p4 = [90, 160]; 
            pe_p5 = [200, 270]; pe_p6 = [220, 290];
            pe_p7 = [300, 370]; pe_p8 = [330, 400];

            perpendicular_line_1_1 = images.roi.Line(app.UIAxes, ...
                'Position', [pe_p1; pe_p2], 'Color', 'g');
            perpendicular_line_1_2 = images.roi.Line(app.UIAxes, ...
                'Position', [pe_p3; pe_p4], 'Color', 'g');

            perpendicular_line_2_1 = images.roi.Line(app.UIAxes, ...
                'Position', [pe_p5; pe_p6], 'Color', 'r');
            perpendicular_line_2_2 = images.roi.Line(app.UIAxes, ...
                'Position', [pe_p7; pe_p8], 'Color', 'r');
            
            app.pep1 = pe_p1;
            app.pep2 = pe_p2;
            app.pep3 = pe_p3;
            app.pep4 = pe_p4;
            app.pep5 = pe_p5;
            app.pep6 = pe_p6;
            app.pep7 = pe_p7;
            app.pep8 = pe_p8;

            addlistener(perpendicular_line_1_1, 'MovingROI', ...
                        @(src, evt) new_perpendicular_1(app, src, evt));

            addlistener(perpendicular_line_1_2, 'MovingROI', ...
                        @(src, evt) new_perpendicular_2(app, src, evt));

            addlistener(perpendicular_line_2_1, 'MovingROI', ...
                        @(src, evt) new_perpendicular_3(app, src, evt));

            addlistener(perpendicular_line_2_2, 'MovingROI', ...
                        @(src, evt) new_perpendicular_4(app, src, evt));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'TARDIS';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'metro-station', 'oil-painting', 'sagrada_familia', 'shopping-mall', 'simple-room', 'uhren-turm'};
            app.DropDown.ItemsData = {'images/metro-station.png', 'images/oil-painting.png', 'images/sagrada_familia.png', 'images/shopping-mall.png', 'images/simple-room.png', 'images/uhren-turm.jpg'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'images/metro-station.png';
            app.PredefinedImagesLabel = uilabel(app.UIFigure);
            app.PredefinedImagesLabel.Text = 'Choose one Image';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VanishedPointTab = uitab(app.TabGroup);
            app.VanishedPointTab.Title = '0 Vanished Point';
            app.VanishedPointTab.ButtonDownFcn = createCallbackFcn(app, @VanishedPointTabButtonDown, true);
            app.Selectfloorpoint0Button = uibutton(app.VanishedPointTab, 'push');
            app.Selectfloorpoint0Button.ButtonPushedFcn = createCallbackFcn(app, @Selectfloorpoint0ButtonPushed, true);
            app.Selectfloorpoint0Button.Text = 'Select floor point 0';
            app.Selectforeground0OptionalButton = uibutton(app.VanishedPointTab, 'push');
            app.Selectforeground0OptionalButton.ButtonPushedFcn = createCallbackFcn(app, @Selectforeground0OptionalButtonPushed, true);
            app.Selectforeground0OptionalButton.Text = {'Select foreground 0 '; '(Optional)'};
            app.Starttour0Button = uibutton(app.VanishedPointTab, 'push');
            app.Starttour0Button.ButtonPushedFcn = createCallbackFcn(app, @Starttour0ButtonPushed, true);
            app.Starttour0Button.Text = 'Start tour 0';
            app.VanishedPointTab_2 = uitab(app.TabGroup);
            app.VanishedPointTab_2.Title = '1 Vanished Point';
            app.VanishedPointTab_2.ButtonDownFcn = createCallbackFcn(app, @VanishedPointTab_2ButtonDown, true);
            app.Selectforground1OptionalButton = uibutton(app.VanishedPointTab_2, 'push');
            app.Selectforground1OptionalButton.ButtonPushedFcn = createCallbackFcn(app, @Selectforground1OptionalButtonPushed, true);
            app.Selectforground1OptionalButton.Text = {'Select forground 1'; '(Optional)'};
            app.StartTour1Button = uibutton(app.VanishedPointTab_2, 'push');
            app.StartTour1Button.ButtonPushedFcn = createCallbackFcn(app, @StartTour1ButtonPushed, true);
            app.StartTour1Button.Text = 'Start Tour 1';
            app.SelectVanishingPointandBackground1Button = uibutton(app.VanishedPointTab_2, 'push');
            app.SelectVanishingPointandBackground1Button.ButtonPushedFcn = createCallbackFcn(app, @SelectVanishingPointandBackground1ButtonPushed, true);
            app.SelectVanishingPointandBackground1Button.Text = {'Select Vanishing Point '; 'and Background 1'};
            app.VanishedPointsTab = uitab(app.TabGroup);
            app.VanishedPointsTab.Title = '2 Vanished Points';
            app.VanishedPointsTab.ButtonDownFcn = createCallbackFcn(app, @VanishedPointsTabButtonDown, true);
            app.Select2ParallelLinesButton = uibutton(app.VanishedPointsTab, 'push');
            app.Select2ParallelLinesButton.ButtonPushedFcn = createCallbackFcn(app, @Select2ParallelLinesButtonPushed, true);
            app.Select2ParallelLinesButton.Text = 'Select 2 Parallel Lines';
            app.ImageRectificationButton = uibutton(app.VanishedPointsTab, 'push');
            app.ImageRectificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImageRectificationButtonPushed, true);
            app.ImageRectificationButton.Text = 'Image Rectification';
            app.Select2PerpendicularLinesButton = uibutton(app.VanishedPointsTab, 'push');
            app.Select2PerpendicularLinesButton.ButtonPushedFcn = createCallbackFcn(app, @Select2PerpendicularLinesButtonPushed, true);
            app.Select2PerpendicularLinesButton.Text = {'Select 2 Perpendicular '; 'Lines'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo', 'TUM_Logo_extern_mt_DE_RGB_p.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'logo', 'group.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TIPAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end